classdef Telecommunaications_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        DisplayAudioButton     matlab.ui.control.Button
        DisplayAudioDropDown   matlab.ui.control.DropDown
        TelegraphLabel         matlab.ui.control.Label
        MorseCodeLabel         matlab.ui.control.Label
        TextLabel              matlab.ui.control.Label
        EditField_Telegraph    matlab.ui.control.EditField
        EditField_3Label       matlab.ui.control.Label
        speedSlider            matlab.ui.control.Slider
        speedSliderLabel       matlab.ui.control.Label
        waveformDropDown       matlab.ui.control.DropDown
        waveformDropDownLabel  matlab.ui.control.Label
        ConvertButton          matlab.ui.control.Button
        convertDropDown        matlab.ui.control.DropDown
        PlayAudioButton        matlab.ui.control.Button
        EditField_2            matlab.ui.control.EditField
        EditField              matlab.ui.control.EditField
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            mode = app.DisplayAudioDropDown.Value;
            switch mode
                case "Display Morse"
                    [MorseSound, ~] = AudioMorse(app.EditField_2.Value, app.waveformDropDown.Value);
                    Audio = audioplayer(MorseSound, round(app.speedSlider.Value));
                case "Display Telegraph"
                    [TelegraphSound, ~] = AudioTelegraph(app.EditField_Telegraph.Value, app.waveformDropDown.Value);
                    Audio = audioplayer(TelegraphSound, round(app.speedSlider.Value));
            end
            pause(0.25);
            play(Audio)
            while isplaying(Audio)
                pause(0.00001);
            end
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            mode = app.convertDropDown.Value;
        
            switch mode
                case "Encode Text"
                    inputText = upper(app.EditField.Value); % String input
                    morseResult = encode(inputText);        % Convert to Morse
                    codes = strings(1, length(inputText));  % Convert to Telegraph
        
                    for i = 1:length(inputText)
                        codes(i) = "0" + telegraph_code(inputText(i)) + "11";
                    end
                    telegraphResult = join(codes, "'");  % Start bit + data + two stop bits
        
                    app.EditField_2.Value = morseResult;
                    app.EditField_Telegraph.Value = telegraphResult;
        
                case "Decode Morse"
                    morseInput = app.EditField_2.Value;      % Morse input
                    decodedText = decode(morseInput);        % Morse → String
                    codes = strings(1, length(decodedText));  % Convert to Telegraph
        
                    for i = 1:length(decodedText)
                        codes(i) = "0" + telegraph_code(decodedText(i)) + "11";
                    end
                    telegraphResult = join(codes, "'");  % Start bit + data + two stop bits
        
                    app.EditField.Value = decodedText;
                    app.EditField_Telegraph.Value = telegraphResult;
        
                case "Decode Telegraph"
                    telegraphInput = app.EditField_Telegraph.Value;  % Telegraph input
                    decodedText = decode_telegraph(telegraphInput);  % Telegraph → String
                    morseResult = encode(decodedText);               % String → Morse
        
                    app.EditField.Value = decodedText;
                    app.EditField_2.Value = morseResult;
            end
        end

        % Value changed function: EditField_Telegraph, convertDropDown
        function EditField_TelegraphValueChanged(app, event)
            value = app.EditField_Telegraph.Value;
        end

        % Value changed function: DisplayAudioDropDown
        function DisplayAudioDropDownValueChanged(app, event)
            value = app.DisplayAudioDropDown.Value;
        end

        % Button pushed function: DisplayAudioButton
        function DisplayAudioButtonPushed(app, event)
            mode = app.DisplayAudioDropDown.Value;
            switch mode
                case "Display Morse"
                    [Sound, ~] = AudioMorse(app.EditField_2.Value, app.waveformDropDown.Value);
                case "Display Telegraph"
                    [Sound, ~] = AudioTelegraph(app.EditField_Telegraph.Value, app.waveformDropDown.Value);
            end
            
            plot(Sound, 'Parent', app.UIAxes);
            ylim(app.UIAxes, [-inf inf]);
            xlim(app.UIAxes, [0 length(Sound)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio Lines')
            zlabel(app.UIAxes, 'Z')
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Tooltip = {'Type text using alphabets and space between words.'};
            app.EditField.Value = 'SOS';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Tooltip = {'Type Morse code using ''.'', ''-'' or ''_'', using apastrophe '' between letters and ''/'' between words.'};
            app.EditField_2.Value = '...''---''...';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.convertDropDown = uidropdown(app.UIFigure);
            app.convertDropDown.Items = {'Encode Text', 'Decode Morse', 'Decode Telegraph'};
            app.convertDropDown.ValueChangedFcn = createCallbackFcn(app, @EditField_TelegraphValueChanged, true);
            app.convertDropDown.Value = 'Encode Text';
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.waveformDropDownLabel = uilabel(app.UIFigure);
            app.waveformDropDownLabel.Text = 'waveform';
            app.waveformDropDown = uidropdown(app.UIFigure);
            app.waveformDropDown.Items = {'sin', 'sawtooth', 'square'};
            app.waveformDropDown.Value = 'sin';
            app.speedSliderLabel = uilabel(app.UIFigure);
            app.speedSliderLabel.Text = 'speed';
            app.speedSlider = uislider(app.UIFigure);
            app.speedSlider.Limits = [5000 30000];
            app.speedSlider.MajorTicks = [10000 20000 30000];
            app.speedSlider.Value = 10000;
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_Telegraph = uieditfield(app.UIFigure, 'text');
            app.EditField_Telegraph.ValueChangedFcn = createCallbackFcn(app, @EditField_TelegraphValueChanged, true);
            app.EditField_Telegraph.Tooltip = {'Type Telegraph code according to ITA2 protocl for Baudot code (Supports Alphabets and space (00100)) such that each character consists of 5 data bits, preceeded by one start bit (0) and succeeded by two stop bits (11). seperate each 8 bit code using apasttrophe '' .'};
            app.EditField_Telegraph.Value = '00010111''01100011''00010111';
            app.TextLabel = uilabel(app.UIFigure);
            app.TextLabel.Text = 'Text:';
            app.MorseCodeLabel = uilabel(app.UIFigure);
            app.MorseCodeLabel.Text = 'Morse Code:';
            app.TelegraphLabel = uilabel(app.UIFigure);
            app.TelegraphLabel.Text = 'Telegraph:';
            app.DisplayAudioDropDown = uidropdown(app.UIFigure);
            app.DisplayAudioDropDown.Items = {'Display Morse', 'Display Telegraph'};
            app.DisplayAudioDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayAudioDropDownValueChanged, true);
            app.DisplayAudioDropDown.Value = 'Display Morse';
            app.DisplayAudioButton = uibutton(app.UIFigure, 'push');
            app.DisplayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayAudioButtonPushed, true);
            app.DisplayAudioButton.Text = 'Display Audio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Telecommunaications_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end