classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        binarycodeTextArea              matlab.ui.control.TextArea
        SymbolsTextArea                 matlab.ui.control.TextArea
        SymbolsTextAreaLabel            matlab.ui.control.Label
        decodeButton                    matlab.ui.control.Button
        decodedstringEditField          matlab.ui.control.EditField
        decodedstringEditFieldLabel     matlab.ui.control.Label
        inputcodedstringEditField       matlab.ui.control.EditField
        inputcodedstringEditFieldLabel  matlab.ui.control.Label
        codeButton                      matlab.ui.control.Button
        codedstringEditField            matlab.ui.control.EditField
        codedstringEditFieldLabel       matlab.ui.control.Label
        inputstringEditField            matlab.ui.control.EditField
        inputstringEditFieldLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: codeButton
        function codeButtonPushed(app, event)
                 input_str=app.inputstringEditField.Value;
                 global codebook ;
                 [codebook, coded_str] = shanon_fano_encoding(input_str);
                 app.codedstringEditField.Value=coded_str;
                 keys = codebook.keys;
                 for i = 1:length(keys)
                         app.SymbolsTextArea.Value{i} = keys{i} ;
                         app.binarycodeTextArea.Value{i} = codebook(keys{i}) ;
                 
                     
                 end


        end

        % Button pushed function: decodeButton
        function decodeButtonPushed(app, event)
            coded_str=app.inputcodedstringEditField.Value;
             global codebook ;
            app.decodedstringEditField.Value = decode_shanon_fano(coded_str, codebook);
        end

        % Callback function
        function TextAreaValueChanged(app, event)
            value = app.SymbolsTextArea.Value;
            global codebook ;
            keys = codebook.keys;
                for i = 1:length(keys)
                     fprintf('%c: %s\n', keys{i}, codebook(keys{i}));
                end

        end

        % Callback function
        function TextAreaValueChanged2(app, event)
        
        end

        % Value changed function: SymbolsTextArea
        function SymbolsTextAreaValueChanged3(app, event)
            value = app.SymbolsTextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.inputstringEditFieldLabel = uilabel(app.UIFigure);
            app.inputstringEditFieldLabel.Text = 'input string';
            app.inputstringEditField = uieditfield(app.UIFigure, 'text');
            app.codedstringEditFieldLabel = uilabel(app.UIFigure);
            app.codedstringEditFieldLabel.Text = 'coded string';
            app.codedstringEditField = uieditfield(app.UIFigure, 'text');
            app.codeButton = uibutton(app.UIFigure, 'push');
            app.codeButton.ButtonPushedFcn = createCallbackFcn(app, @codeButtonPushed, true);
            app.codeButton.Text = 'code';
            app.inputcodedstringEditFieldLabel = uilabel(app.UIFigure);
            app.inputcodedstringEditFieldLabel.Text = 'input coded string';
            app.inputcodedstringEditField = uieditfield(app.UIFigure, 'text');
            app.decodedstringEditFieldLabel = uilabel(app.UIFigure);
            app.decodedstringEditFieldLabel.Text = 'decoded string';
            app.decodedstringEditField = uieditfield(app.UIFigure, 'text');
            app.decodeButton = uibutton(app.UIFigure, 'push');
            app.decodeButton.ButtonPushedFcn = createCallbackFcn(app, @decodeButtonPushed, true);
            app.decodeButton.Text = 'decode';
            app.SymbolsTextAreaLabel = uilabel(app.UIFigure);
            app.SymbolsTextAreaLabel.Text = 'Symbols';
            app.SymbolsTextArea = uitextarea(app.UIFigure);
            app.SymbolsTextArea.ValueChangedFcn = createCallbackFcn(app, @SymbolsTextAreaValueChanged3, true);
            app.binarycodeTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end