classdef Audio_Transmission_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SecondvoiceListBox             matlab.ui.control.ListBox
        SecondvoiceListBoxLabel        matlab.ui.control.Label
        FirstvoiceListBox              matlab.ui.control.ListBox
        FirstvoiceListBoxLabel         matlab.ui.control.Label
        SecondvoisegainEditField       matlab.ui.control.NumericEditField
        SecondvoisegainEditFieldLabel  matlab.ui.control.Label
        FirstvoisegainEditField        matlab.ui.control.NumericEditField
        FirstvoisegainEditFieldLabel   matlab.ui.control.Label
        LoadSignalsButton              matlab.ui.control.Button
        PlayFilteredAudioButton        matlab.ui.control.Button
        ApplyFilterButton              matlab.ui.control.Button
        PlayNoisyAudioButton           matlab.ui.control.Button
        AddNoiseButton                 matlab.ui.control.Button
        NoiseSlider                    matlab.ui.control.Slider
        NoiseSliderLabel               matlab.ui.control.Label
        PlayDistortedAudioButton       matlab.ui.control.Button
        LossEditField                  matlab.ui.control.NumericEditField
        LossEditFieldLabel             matlab.ui.control.Label
        TrasmitterGainEditField        matlab.ui.control.NumericEditField
        TrasmitterGainEditFieldLabel   matlab.ui.control.Label
        ApplyChannelButton             matlab.ui.control.Button
        ChannelsListBox                matlab.ui.control.ListBox
        ChannelsListBoxLabel           matlab.ui.control.Label
        PlayAudioButton                matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        origialSignal1;
        origialSignal2;
        main;
        frequencySignal;
        Fs;
        tvec;
        fvec;
        loss;
        gainLinear;
        Transmitteroutput;
        channelRespose;
        channelOutput;
        channelOutputFre;
        noise;
        noisySignal;
        tn;
        fn;
        sig_ff;
        filteredSignal;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function LoadButtonPushed(app, event)
            
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            sound(app.main, app.Fs);
        end

        % Value changed function: ChannelsListBox
        function ChannelsListBoxValueChanged(app, event)

        end

        % Button pushed function: ApplyChannelButton
        function ApplyChannelButtonPushed(app, event)
            t = linspace(0,0.5,0.5*app.Fs);
            switch app.ChannelsListBox.Value
                case 'sinc(2pi100t)'
                    app.channelRespose = sinc(2*pi*100*t);
                case 'exp(-2pi5000t)'
                    app.channelRespose = exp(-2*pi*5000*t);
                case 'exp(-2pi1000t)'
                    app.channelRespose = exp(-2*pi*1000*t);
                case '2delta(t)+0.5delta(t+1)'
                    app.channelRespose = [2 zeros(1,0.5*app.Fs) 0.5];
            end
            app.gainLinear = 10^(app.TrasmitterGainEditField.Value / 10);
            app.loss = 0.1^(app.LossEditField.Value / 10);
            app.Transmitteroutput = app.main * app.gainLinear * app.loss;            
            app.channelOutput = conv(app.Transmitteroutput,app.channelRespose,"same");
            app.channelOutputFre = abs(fftshift(fft(app.channelOutput)));
            f = linspace(-app.Fs/4,app.Fs/4,length(app.channelOutputFre));
            plot(app.UIAxes2,f,app.channelOutputFre);

        end

        % Value changed function: TrasmitterGainEditField
        function TrasmitterGainEditFieldValueChanged(app, event)
            value = app.TrasmitterGainEditField.Value;
        end

        % Value changed function: LossEditField
        function LossEditFieldValueChanged(app, event)
            value = app.LossEditField.Value;
        end

        % Button pushed function: PlayDistortedAudioButton
        function PlayDistortedAudioButtonPushed(app, event)
            sound(app.channelOutput,app.Fs);
        end

        % Callback function
        function AddChannelEditFieldValueChanging(app, event)
            
        end

        % Value changing function: NoiseSlider
        function NoiseSliderValueChanging(app, event)
            changingValue = event.Value;
            app.noise = changingValue*randn(size(app.channelOutput));
            app.noisySignal = app.channelOutput + app.noise;
        end

        % Button pushed function: AddNoiseButton
        function AddNoiseButtonPushed(app, event)
            app.tn = linspace(0,length(app.noisySignal)/app.Fs,length(app.noisySignal));
            app.sig_ff = abs(fftshift(fft(app.noisySignal)));
            app.fn = linspace(-app.Fs/2,app.Fs/2,length(app.sig_ff));
            plot(app.UIAxes2,app.fn,app.sig_ff);
        end

        % Button pushed function: PlayNoisyAudioButton
        function PlayNoisyAudioButtonPushed(app, event)
            sound(app.noisySignal,app.Fs);
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            fc = 3000;
            n_order = 3;
            [b,a] = butter(n_order, fc/(app.Fs/2));
            app.filteredSignal = filtfilt(b,a,app.noisySignal);
            sig_filtered_f = abs(fftshift(fft(app.filteredSignal)));
            plot(app.UIAxes2,app.fn,sig_filtered_f);
        end

        % Button pushed function: PlayFilteredAudioButton
        function PlayFilteredAudioButtonPushed(app, event)
            sound(app.filteredSignal,app.Fs);
        end

        % Button pushed function: LoadSignalsButton
        function LoadSignalsButtonPushed(app, event)

            switch app.FirstvoiceListBox.Value
                case 'obama1'
                    [app.origialSignal1,app.Fs] = audioread('obama1.wav');
                case 'obama2'
                    [app.origialSignal1,app.Fs] = audioread('obama2.wav');
                case 'obama3'
                    [app.origialSignal1,app.Fs] = audioread('obama3.wav');
                
                   
            end
            switch app.SecondvoiceListBox.Value
                case 'obama1'
                    [app.origialSignal2,app.Fs] = audioread('obama1.wav');
                case 'obama2'
                    [app.origialSignal2,app.Fs] = audioread('obama2.wav');
                case 'obama3'
                    [app.origialSignal2,app.Fs] = audioread('obama3.wav');
             
            end

            app.main = app.origialSignal1(1:69632) * app.FirstvoisegainEditField.Value + app.origialSignal2(1:69632) * app.SecondvoisegainEditField.Value;
            app.tvec = linspace(0,length(app.main)/app.Fs,length(app.main));
            app.frequencySignal = abs(fftshift(fft(app.main)));
            app.fvec = linspace(-app.Fs/4,app.Fs/4,length(app.frequencySignal));
            plot(app.UIAxes2,app.fvec,app.frequencySignal);
        end

        % Value changed function: FirstvoisegainEditField
        function FirstvoisegainEditFieldValueChanged(app, event)
            value = app.FirstvoisegainEditField.Value;
            
        end

        % Value changed function: SecondvoisegainEditField
        function SecondvoisegainEditFieldValueChanged(app, event)
            value = app.SecondvoisegainEditField.Value;
            
        end

        % Value changed function: FirstvoiceListBox
        function FirstvoiceListBoxValueChanged(app, event)
            value = app.FirstvoiceListBox.Value;
            
        end

        % Value changed function: SecondvoiceListBox
        function SecondvoiceListBoxValueChanged(app, event)
            value = app.SecondvoiceListBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Frequency Domain')
            xlabel(app.UIAxes2, 'Freq (Hz)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.ChannelsListBoxLabel = uilabel(app.UIFigure);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.ChannelsListBox = uilistbox(app.UIFigure);
            app.ChannelsListBox.Items = {'sinc(2pi100t)', 'exp(-2pi5000t)', 'exp(-2pi1000t)', '2delta(t)+0.5delta(t+1)'};
            app.ChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelsListBoxValueChanged, true);
            app.ChannelsListBox.Value = 'sinc(2pi100t)';
            app.ApplyChannelButton = uibutton(app.UIFigure, 'push');
            app.ApplyChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChannelButtonPushed, true);
            app.ApplyChannelButton.Text = 'Apply Channel';
            app.TrasmitterGainEditFieldLabel = uilabel(app.UIFigure);
            app.TrasmitterGainEditFieldLabel.Text = 'Trasmitter Gain';
            app.TrasmitterGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrasmitterGainEditField.ValueChangedFcn = createCallbackFcn(app, @TrasmitterGainEditFieldValueChanged, true);
            app.TrasmitterGainEditField.Value = 1;
            app.LossEditFieldLabel = uilabel(app.UIFigure);
            app.LossEditFieldLabel.Text = 'Loss';
            app.LossEditField = uieditfield(app.UIFigure, 'numeric');
            app.LossEditField.ValueChangedFcn = createCallbackFcn(app, @LossEditFieldValueChanged, true);
            app.PlayDistortedAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayDistortedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayDistortedAudioButtonPushed, true);
            app.PlayDistortedAudioButton.Text = 'Play Distorted Audio';
            app.NoiseSliderLabel = uilabel(app.UIFigure);
            app.NoiseSliderLabel.Text = 'Noise';
            app.NoiseSlider = uislider(app.UIFigure);
            app.NoiseSlider.Limits = [0.01 10];
            app.NoiseSlider.MajorTicks = [0.01 2 4 6 8 10];
            app.NoiseSlider.ValueChangingFcn = createCallbackFcn(app, @NoiseSliderValueChanging, true);
            app.NoiseSlider.Value = 0.01;
            app.AddNoiseButton = uibutton(app.UIFigure, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'Add Noise';
            app.PlayNoisyAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayNoisyAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayNoisyAudioButtonPushed, true);
            app.PlayNoisyAudioButton.Text = 'Play Noisy Audio';
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.PlayFilteredAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayFilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredAudioButtonPushed, true);
            app.PlayFilteredAudioButton.Text = 'Play Filtered Audio';
            app.LoadSignalsButton = uibutton(app.UIFigure, 'push');
            app.LoadSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSignalsButtonPushed, true);
            app.LoadSignalsButton.Text = 'Load Signals';
            app.FirstvoisegainEditFieldLabel = uilabel(app.UIFigure);
            app.FirstvoisegainEditFieldLabel.Text = 'First voise gain';
            app.FirstvoisegainEditField = uieditfield(app.UIFigure, 'numeric');
            app.FirstvoisegainEditField.Limits = [0 1];
            app.FirstvoisegainEditField.ValueChangedFcn = createCallbackFcn(app, @FirstvoisegainEditFieldValueChanged, true);
            app.FirstvoisegainEditField.Value = 1;
            app.SecondvoisegainEditFieldLabel = uilabel(app.UIFigure);
            app.SecondvoisegainEditFieldLabel.Text = 'Second voise gain';
            app.SecondvoisegainEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondvoisegainEditField.Limits = [0 1];
            app.SecondvoisegainEditField.ValueChangedFcn = createCallbackFcn(app, @SecondvoisegainEditFieldValueChanged, true);
            app.FirstvoiceListBoxLabel = uilabel(app.UIFigure);
            app.FirstvoiceListBoxLabel.Text = 'First voice';
            app.FirstvoiceListBox = uilistbox(app.UIFigure);
            app.FirstvoiceListBox.Items = {'obama1', 'obama2', 'obama3'};
            app.FirstvoiceListBox.ValueChangedFcn = createCallbackFcn(app, @FirstvoiceListBoxValueChanged, true);
            app.FirstvoiceListBox.Value = 'obama1';
            app.SecondvoiceListBoxLabel = uilabel(app.UIFigure);
            app.SecondvoiceListBoxLabel.Text = 'Second voice';
            app.SecondvoiceListBox = uilistbox(app.UIFigure);
            app.SecondvoiceListBox.Items = {'obama1', 'obama2', 'obama3'};
            app.SecondvoiceListBox.ValueChangedFcn = createCallbackFcn(app, @SecondvoiceListBoxValueChanged, true);
            app.SecondvoiceListBox.Value = 'obama1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audio_Transmission_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end