classdef DeltaModulationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ExapleInput5sin2piftexptLabel  matlab.ui.control.Label
        PleasenotetochangethefrequencyandsamplingratetoyoursignalLabel  matlab.ui.control.Label
        fEditField                     matlab.ui.control.NumericEditField
        fEditFieldLabel                matlab.ui.control.Label
        FsEditField                    matlab.ui.control.NumericEditField
        FsEditFieldLabel               matlab.ui.control.Label
        ModulationDropDown             matlab.ui.control.DropDown
        ModulationDropDownLabel        matlab.ui.control.Label
        ExampleButton                  matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        ImportButton                   matlab.ui.control.Button
        ExportButton_2                 matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        InputSignalEditField           matlab.ui.control.EditField
        KEditField                     matlab.ui.control.NumericEditField
        KEditFieldLabel                matlab.ui.control.Label
        DeltaEditField                 matlab.ui.control.NumericEditField
        DeltaEditFieldLabel            matlab.ui.control.Label
        NoiseCheckBox                  matlab.ui.control.CheckBox
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

   
    properties (Access = private)
        S               % input signal
        f=2;            % input signal frequency
        Fs=50;          % sampling frequency
        t               % time vector
        Delta=1;        % step size
        K=2             % Delta modifier for ADM
        predict         % prediction output of Modulation
        demod           % demodulation output
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NoiseCheckBox
        function NoiseCheckBoxValueChanged(app, event)
            value = app.NoiseCheckBox.Value;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % getting input from user
            [file, path] = uigetfile('*.mat', 'Select Signal File');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                % loading and reading input
                signalData = load(fullfile(path, file));
                signalName = fieldnames(signalData);
                app.S = signalData.(signalName{1});
        
                len = length(app.S);                   % length of input signal
                app.t = linspace(0, len/app.Fs, len);  % time vector
                app.InputSignalEditField.Value = signalName{1};
                
            end
            figure(app.UIFigure);
        end

        % Value changed function: InputSignalEditField
        function InputSignalEditFieldValueChanged(app, event)
            value = app.InputSignalEditField.Value;
        end

        % Button pushed function: ExampleButton
        function ExampleButtonPushed(app, event)
            %Sin*exp function
            len = 500;                      % length of signal
            app.t = linspace(0, 10, len);   % time vector
            app.Fs = 50;                    % sampling frequency
            amp = 5;                        % amplitude of signal
            app.f = 2;                      % frequency of signal
            app.S = amp*sin(2*pi*app.f*app.t).*exp(-app.t);
            app.Delta = 1;
            app.InputSignalEditField.Value = "5*sin(2*pi*2t).*exp(-t)";
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
                        
            x = app.S;
            delta = app.Delta;
            k = app.K;
            
            % Adding Noise
            ckb = app.NoiseCheckBox.Value;
            if (ckb == 1) 
                x = x + 0.05*randn(size(x));
            else
                x = app.S;
            end
            
            % Delta Modulation
            [dm_out, dm_pred] = delta_mod(x, delta);

            % Demodulation
            dm_demod = delta_mod_demod(dm_out, delta, app.f, app.Fs);

            
            % ADM - Jayant Algorithm
            [adm_out, adm_pred] = adm_modulator(x, delta, app.K);

            % Demodulation
            adm_demod = adm_demodulator(adm_out, delta, app.K, app.f, app.Fs);
            
            val = app.ModulationDropDown.Value;
            if (strcmpi(val,'DM')==1)
                
                app.predict = dm_pred;
                app.demod = dm_demod;
                
                
                % plotting Delta Modulation
                plot(app.UIAxes, app.t, x,'b', 'LineWidth', 2); % plot input
                hold(app.UIAxes, 'on');
                stairs(app.UIAxes, app.t, dm_pred,'r', 'LineWidth', 1); % plot DM pred
                legend(app.UIAxes, 'Input', 'DM predicted');
                grid(app.UIAxes, 'on');     % enable grid
                zoom(app.UIAxes, 'on');     % enable zoom
                hold(app.UIAxes, 'off');

                %Input and Demodulation Comparison
                plot(app.UIAxes2, app.t, x,'b', 'LineWidth', 2); % plot input
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes2, app.t, dm_demod,'color', [56/255,166/255,165/255], 'LineWidth', 2); % plot DM dem
                legend(app.UIAxes2, 'Input', 'Demodulation');
                grid(app.UIAxes2, 'on');    % enable grid
                zoom(app.UIAxes2, 'on');    % enable zoom
                hold(app.UIAxes2, 'off');

            else
                
                app.predict = adm_pred;    % assigning outputs for ExportButton
                app.demod = adm_demod;  % assigning outputs for ExportButton2
                
                % plotting ADM
                plot(app.UIAxes, app.t, x,'b', 'LineWidth', 2); % plot imput
                hold(app.UIAxes, 'on');
                stairs(app.UIAxes, app.t, adm_pred','r', 'LineWidth', 1); % plot ADM pred
                legend(app.UIAxes, 'Input', 'ADM predicted');
                grid(app.UIAxes, 'on');     % sets the grid on
                zoom(app.UIAxes, 'on');     % enable zoom
                hold(app.UIAxes, 'off');

                %Input and Demodulation Comparison
                plot(app.UIAxes2, app.t, x,'b', 'LineWidth', 2); % plot input
                hold(app.UIAxes2, 'on');                
                plot(app.UIAxes2, app.t, adm_demod,'color', [56/255,166/255,165/255], 'LineWidth', 2); %plot ADM dem
                legend(app.UIAxes2, 'Input', 'Demodulation');
                grid(app.UIAxes2, 'on');    % enbale grid
                zoom(app.UIAxes2, 'on');    % enable zoom
                hold(app.UIAxes2, 'off');                
            end
        end

        % Value changed function: ModulationDropDown
        function ModulationDropDownValueChanged(app, event)
            value = app.ModulationDropDown.Value;
        end

        % Value changed function: FsEditField
        function FsEditFieldValueChanged(app, event)
            value = app.FsEditField.Value;
            app.Fs = app.FsEditField.Value;
        end

        % Value changed function: fEditField
        function fEditFieldValueChanged(app, event)
            value = app.fEditField.Value;
            app.f = app.fEditField.Value;
        end

        % Value changed function: DeltaEditField
        function DeltaEditFieldValueChanged(app, event)
            value = app.DeltaEditField.Value;
            app.Delta = app.DeltaEditField.Value;
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            value = app.KEditField.Value;
            app.K =app.KEditField.Value;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            assignin('base','predict', app.predict);
            %saveas(app.UIAxes, 'Mod', 'fig')
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
            assignin('base','demod', app.demod)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Modulation')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Amlitude(V)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Demodulation')
            xlabel(app.UIAxes2, 'Time(s)')
            ylabel(app.UIAxes2, 'Amlitude(V)')
            app.NoiseCheckBox = uicheckbox(app.UIFigure);
            app.NoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoiseCheckBoxValueChanged, true);
            app.NoiseCheckBox.Text = 'Noise';
            app.DeltaEditFieldLabel = uilabel(app.UIFigure);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeltaEditField.Limits = [0 10];
            app.DeltaEditField.ValueDisplayFormat = '%.2f';
            app.DeltaEditField.ValueChangedFcn = createCallbackFcn(app, @DeltaEditFieldValueChanged, true);
            app.DeltaEditField.Value = 1;
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditField.Limits = [1 2];
            app.KEditField.ValueDisplayFormat = '%.2f';
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.KEditField.Value = 2;
            app.InputSignalEditField = uieditfield(app.UIFigure, 'text');
            app.InputSignalEditField.ValueChangedFcn = createCallbackFcn(app, @InputSignalEditFieldValueChanged, true);
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ExportButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExampleButton = uibutton(app.UIFigure, 'push');
            app.ExampleButton.ButtonPushedFcn = createCallbackFcn(app, @ExampleButtonPushed, true);
            app.ExampleButton.Text = 'Example';
            app.ModulationDropDownLabel = uilabel(app.UIFigure);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ModulationDropDown = uidropdown(app.UIFigure);
            app.ModulationDropDown.Items = {'DM', 'ADM'};
            app.ModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationDropDownValueChanged, true);
            app.ModulationDropDown.Value = 'ADM';
            app.FsEditFieldLabel = uilabel(app.UIFigure);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FsEditField.Limits = [0 Inf];
            app.FsEditField.ValueChangedFcn = createCallbackFcn(app, @FsEditFieldValueChanged, true);
            app.FsEditField.Value = 50;
            app.fEditFieldLabel = uilabel(app.UIFigure);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.UIFigure, 'numeric');
            app.fEditField.Limits = [0 Inf];
            app.fEditField.ValueChangedFcn = createCallbackFcn(app, @fEditFieldValueChanged, true);
            app.fEditField.Value = 2;
            app.PleasenotetochangethefrequencyandsamplingratetoyoursignalLabel = uilabel(app.UIFigure);
            app.PleasenotetochangethefrequencyandsamplingratetoyoursignalLabel.Text = '* Please note to change the frequency and sampling rate according to your signal *';
            app.ExapleInput5sin2piftexptLabel = uilabel(app.UIFigure);
            app.ExapleInput5sin2piftexptLabel.Text = 'Exaple Input : 5*sin(2*pi*f*t).*exp(-t)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeltaModulationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end